classdef SimuladorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        ClosedLoopCheckBox             matlab.ui.control.CheckBox
        InsulinunitsDropDownLabel      matlab.ui.control.Label
        InsulinunitsDropDown           matlab.ui.control.DropDown
        GlycemiaunitsDropDownLabel     matlab.ui.control.Label
        GlycemiaunitsDropDown          matlab.ui.control.DropDown
        CLPanel                        matlab.ui.container.Panel
        AddnewcontrollerButton         matlab.ui.control.Button
        ControllersDropDownLabel       matlab.ui.control.Label
        ControllersDropDown            matlab.ui.control.DropDown
        OLPanel                        matlab.ui.container.Panel
        basalSpinnerLabel              matlab.ui.control.Label
        basalSpinner                   matlab.ui.control.Spinner
        CRSpinnerLabel                 matlab.ui.control.Label
        CRSpinner                      matlab.ui.control.Spinner
        ScenarioTab                    matlab.ui.container.Tab
        ImportscenarioButton           matlab.ui.control.Button
        PatientsPanel                  matlab.ui.container.Panel
        AvailablepatientsListBoxLabel  matlab.ui.control.Label
        AvailablePatientsListBox       matlab.ui.control.ListBox
        AddpatientstosimulationButton  matlab.ui.control.Button
        ViewselectedpatientsButton     matlab.ui.control.Button
        MealsPanel                     matlab.ui.container.Panel
        MisestimationCheckBox          matlab.ui.control.CheckBox
        LowerboundSpinnerLabel         matlab.ui.control.Label
        LowerboundSpinner              matlab.ui.control.Spinner
        UpperboundSpinnerLabel         matlab.ui.control.Label
        UpperboundSpinner              matlab.ui.control.Spinner
        UITable                        matlab.ui.control.Table
        ScenarioSavingPanel            matlab.ui.container.Panel
        ModifyScenarioSavingCheckBox   matlab.ui.control.CheckBox
        SavescenarioCheckBox           matlab.ui.control.CheckBox
        ModifySavingPanel              matlab.ui.container.Panel
        PathnameEditField              matlab.ui.control.EditField
        ChecknameButton                matlab.ui.control.Button
        BrowseScenarioPath             matlab.ui.control.Button
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        ChangePathLabel                matlab.ui.control.Label
        ImportscenariofomfileCheckBox  matlab.ui.control.CheckBox
        ResultsfileTab                 matlab.ui.container.Tab
        ResultsFilePanel               matlab.ui.container.Panel
        ResultsNameField               matlab.ui.control.EditField
        ResultsPathField               matlab.ui.control.EditField
        BrowsepathButton               matlab.ui.control.Button
        ResultsSaveName                matlab.ui.control.Button
        PathLabel                      matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        ShowCVGACheckBox               matlab.ui.control.CheckBox
        ShowpopulationCGMCheckBox      matlab.ui.control.CheckBox
        ShowindividualpatientCGMCheckBox  matlab.ui.control.CheckBox
        ShowpopulationpumpCheckBox     matlab.ui.control.CheckBox
        ShowindividualpatientpumpCheckBox  matlab.ui.control.CheckBox
        StandarizedCGMmetricsPanel     matlab.ui.container.Panel
        TimeblocksCheckBox             matlab.ui.control.CheckBox
        TimePanel                      matlab.ui.container.Panel
        hblocksCheckBox                matlab.ui.control.CheckBox
        DayblocksCheckBox              matlab.ui.control.CheckBox
        NightblocksCheckBox            matlab.ui.control.CheckBox
        CGMPanel                       matlab.ui.container.Panel
        MeanglucoseCheckBox            matlab.ui.control.CheckBox
        GlycemicvariabilityCheckBox    matlab.ui.control.CheckBox
        Time250mgdLCheckBox            matlab.ui.control.CheckBox
        Time70180mgdLCheckBox          matlab.ui.control.CheckBox
        Time70mgdLCheckBox             matlab.ui.control.CheckBox
        Time54mgdLCheckBox             matlab.ui.control.CheckBox
        LBGIriskindexCheckBox          matlab.ui.control.CheckBox
        HBGIriskindexCheckBox          matlab.ui.control.CheckBox
        AreaunderthecurveCheckBox      matlab.ui.control.CheckBox
        Time180mgdLCheckBox            matlab.ui.control.CheckBox
        ResultsFileCheckBox            matlab.ui.control.CheckBox
        SaveresultsCheckBox            matlab.ui.control.CheckBox
        HardwareTab                    matlab.ui.container.Tab
        CGMsensorCheckBox              matlab.ui.control.CheckBox
        CGMdrop                        matlab.ui.control.DropDown
        CSIICheckBox                   matlab.ui.control.CheckBox
        CSIIdrop                       matlab.ui.control.DropDown
        ConfigurationLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        PatientsValues; % Values of AvailablePatientsListBox patients
        SelectedPatients; % Values of selected patients (Handler)
        ScenarioPath; % Path where to save or load scenarios
        ScenarioName; % Name of scenario to save or load
        ScenarioValues; % Scenario values from file
        ControllerCL; % Controller file name if CL
        ImportedScenario; % Scenario file name if imported
        ResultsPath; % Path where to save results
        ResultsName; % Name of results file to save
        CRValue; % Value of CR
        BasalValue; % Value of basal
        LowerBound; % Value of lower bound
        UpperBound; % Value of upper bound
        CLVariables; % Struct with closed loop variables
    end
    
    properties (Access = private)
        ControllerOptionsApp % Controller options dialog box app
    end
    
    methods (Access = public)
        
        function updateController(app, values)
            app.AddnewcontrollerButton.Enable = true;
            app.CLVariables.k0 = values.k0;
            app.CLVariables.k1 = values.k1;
            app.CLVariables.k2 = values.k2;
            app.CLVariables.target = values.target;
            app.CLVariables.gamma = values.gamma;
            app.CLVariables.Kp = values.Kp;
            app.CLVariables.Ti = values.Ti;
            app.CLVariables.Td = values.Td;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% UPDATE OTHER CLVariables VALUES WITH THE ENTERED ONES %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            variables = struct2cell(app.CLVariables);
            [file,path] = uiputfile('*.csv','Save new controller');
            filename = fullfile(path,file);
            csvwrite(filename,variables);
            
            ControllerList(app);
            % Set new controller as the selected one
        end
        
        function cancelController(app)
            app.AddnewcontrollerButton.Enable = true;
        end
    end
    
    methods (Access = private)
        function StandarizedCGM(app,e)
            
        end
        
        function ShowCGMMetrics(app,tipus,patients)
            if strcmp(tipus,'individual') == 1
                for i=1:length(patients)
                    StandarizedCGM(patients(i));
                end
            end
            
            if strcmp(tipus,'population') == 1
                
            end
        end
        
        function ShowPumpMetrics(app,tipus,patients)
            if strcmp(tipus,'individual') == 1
                
            end
            
            if strcmp(tipus,'population') == 1
                
            end
        end
    
        
        function ControllerList(app)
            controllersFiles = dir(fullfile('SimuladorT1DM\controllers\', '*.csv'));
            controllersNames = {controllersFiles.name}';
            app.ControllersDropDown.Items = regexprep(controllersNames, '.csv', '');
        end
        
        function ReadValuesFromControllerFile(app,controller)
            controller_file = strcat(controller, '.csv');
            controllerValues = csvread(strcat('SimuladorT1DM\controllers\',controller_file));
            app.CLVariables.k0 = controllerValues(1);
            app.CLVariables.k1 = controllerValues(2);
            app.CLVariables.k2 = controllerValues(3);
            app.CLVariables.target = controllerValues(4);
            app.CLVariables.gamma = controllerValues(5);
            app.CLVariables.Kp = controllerValues(6);
            app.CLVariables.Ti = controllerValues(7);
            app.CLVariables.Td = controllerValues(8);
            app.CLVariables.SG = controllerValues(9);
            app.CLVariables.Ip_1 = controllerValues(10);
            app.CLVariables.Ip_2 = controllerValues(11);
            app.CLVariables.In_1 = controllerValues(12);
            app.CLVariables.ID_1 = controllerValues(13);
            app.CLVariables.IDn = controllerValues(14);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize CL checkbox
            CLCheckBoxValueChanged(app,false);
            % Initialize available controllers
            ControllerList(app);
            % Initialize default CL values
            app.CLVariables.k0=0;
            app.CLVariables.k1=0;
            app.CLVariables.k2=0;
            app.CLVariables.target=0;
            app.CLVariables.gamma=0;
            app.CLVariables.Kp=0;
            app.CLVariables.Ti=0;
            app.CLVariables.Td=0;
            app.CLVariables.SG=0;
            app.CLVariables.Ip_1=0.5;
            app.CLVariables.Ip_2=0.5;
            app.CLVariables.In_1=0;
            app.CLVariables.ID_1=0;
            app.CLVariables.IDn=0;
            
            % Initialize Import scenario checkbox
            ImportScenarioCheckBoxValueChanged(app,false);
            % Initialize Patients
            patientsFiles = dir(fullfile('SimuladorT1DM\patients\', '*.csv'));
            patientsNames = {patientsFiles.name}';
            app.AvailablePatientsListBox.Items = regexprep(patientsNames,'.csv','');
            % Initialize meal misestimation
            MealMisestimationCheckBoxValueChanged(app,false);
            % Initialize scenario saving configuration
            app.ScenarioPath = "SimuladorT1DM\scenarios\";
            scenarios = dir(fullfile(app.ScenarioPath, '*.mat'));
            if isempty(scenarios)
                app.ScenarioName = 'scenario1.mat';
            else
                scenarioNum = length(scenarios);
                app.ScenarioName = strcat('scenario', num2str(scenarioNum+1), '.mat');
            end
            SaveScenarioCheckBoxValueChanged(app,false);
            ModifyScenarioSavingCheckBoxValueChanged(app,false);
            
            % Initialize Results File Configuration chechbox
            ResultsFileCheckBoxValueChanged(app,false);
            app.ResultsPath = "SimuladorT1DM\results\";
            results = dir(fullfile(app.ResultsPath, '*.txt'));
            if isempty(results)
                app.ResultsName = 'results1.txt';
            else
                resultNum = length(results);
                app.ResultsName = strcat('results', num2str(resultNum+1), '.txt');
            end
            % Inicializate results output
            ShowCVGACheckBoxValueChanged(app,false);
            
            % Initialize Hardware checkboxes
            CGMsensorCheckBoxValueChanged(app,false);
            CSIICheckBoxValueChanged(app,false);
            
            % Initialize data table
            myData = {'Initial BG [mg/dl]' 0;
                        'Meal intake [gr]' 0;
                        'Meal time [hh]' 0;
                        'Meals duration [min]' 0;
                        'Simulation time [days]' 0
                        };
            app.UITable.Data = myData;
            app.UITable.ColumnName = ({});
        end

        % Value changed function: ClosedLoopCheckBox
        function CLCheckBoxValueChanged(app, event)
            value = app.ClosedLoopCheckBox.Value;
            CLpanel = app.CLPanel.Children;
            if value == true
                % If CL is checked
                set(CLpanel,'Enable',true);
            else
                % If CL is unchcked
                set(CLpanel,'Enable',false);
            end
        end

        % Value changed function: ImportscenariofomfileCheckBox
        function ImportScenarioCheckBoxValueChanged(app, event)
            value = app.ImportscenariofomfileCheckBox.Value;
            meals = app.MealsPanel.Children;
            saving = app.ModifySavingPanel.Children;
            
            if value == true
                % If Import scenario from file is enabled, disable tab
                app.ImportscenarioButton.Enable = true;
                app.AvailablePatientsListBox.Enable = false;
                app.AddpatientstosimulationButton.Enable = false;
                set(meals,'Enable','off');
                set(saving,'Enable',false);
                app.SavescenarioCheckBox.Enable = false;
                app.ModifyScenarioSavingCheckBox.Enable = false;
            else
                % If Import scenario from file is disabled, enable tab
                app.ImportscenarioButton.Enable = false;
                app.AvailablePatientsListBox.Enable = true;
                app.AddpatientstosimulationButton.Enable = true;
                set(meals,'Enable','on');
                app.SavescenarioCheckBox.Enable = true;
                MealMisestimationCheckBoxValueChanged(app,false);
            end
        end

        % Value changed function: ResultsFileCheckBox
        function ResultsFileCheckBoxValueChanged(app, event)
            value = app.ResultsFileCheckBox.Value;
            results = app.ResultsFilePanel.Children;
            if value == true
                % If Results file is enabled, enable path and name
                set(results,'Enable',true);
                app.ResultsPathField.Value = app.ResultsPath;
                app.ResultsNameField.Value = app.ResultsName;
            else
                 % If Results file is disabled, disable path and name
                set(results,'Enable',false);
            end
        end

        % Value changed function: CGMsensorCheckBox
        function CGMsensorCheckBoxValueChanged(app, event)
            value = app.CGMsensorCheckBox.Value;
            if value == true
                % If CGM sensor is enabled, enable CGM drop down
                app.CGMdrop.Enable = true;
            else
                % If CGM sensor is disabled, disable CGM drop down
                app.CGMdrop.Enable = false;
            end
        end

        % Value changed function: CSIICheckBox
        function CSIICheckBoxValueChanged(app, event)
            value = app.CSIICheckBox.Value;
            if value == true
                % If CSII sensor is enabled, enable CSII drop down
                app.CSIIdrop.Enable = true;
            else
                % If CSII sensor is disabled, disable CSII drop down
                app.CSIIdrop.Enable = false;
            end
        end

        % Button pushed function: AddpatientstosimulationButton
        function AddPatientsButtonPushed(app, event)
            app.SelectedPatients = app.AvailablePatientsListBox.Value;
        end

        % Button pushed function: ViewselectedpatientsButton
        function ViewSelectedPatientsButtonPushed(app, event)
            % Get the Selected Patients Values
            app.PatientsValues = zeros(length(app.SelectedPatients),20);
            patients = strcat(app.SelectedPatients,'.csv');
            for i = 1:length(app.SelectedPatients)
                app.PatientsValues(i,:) = csvread(strcat("SimuladorT1DM\patients\",patients(i)));
            end
            
            % Plot the Selected Patients Names
            fig = uifigure;
            fig.Position(3:4) = [340 350];
            table_names = table(app.SelectedPatients','VariableNames',"Selected Patients");
            uitable(fig,'Data', table_names);
        end

        % Value changed function: SavescenarioCheckBox
        function SaveScenarioCheckBoxValueChanged(app, event)
            value = app.SavescenarioCheckBox.Value;
            if value == true
                % If save scenario, enable modify configuration
                app.ModifyScenarioSavingCheckBox.Enable = true;
                app.FilenameEditField.Value = app.ScenarioName;
                app.PathnameEditField.Value = app.ScenarioPath;
            else
                % If not save scenario, disable modify configuration
                app.ModifyScenarioSavingCheckBox.Enable = false;
            end
        end

        % Value changed function: ModifyScenarioSavingCheckBox
        function ModifyScenarioSavingCheckBoxValueChanged(app, event)
            value = app.ModifyScenarioSavingCheckBox.Value;
            saving = app.ModifySavingPanel.Children;
            if value == true
                % If scenario saving modified, enable fields
                set(saving,'Enable',true);
            else
                % If not scenario saving modified, disable fields
                set(saving,'Enable',false);
            end
        end

        % Value changed function: MisestimationCheckBox
        function MealMisestimationCheckBoxValueChanged(app, event)
            value = app.MisestimationCheckBox.Value;
            if value == true
                % If consider misestimation, enable bounds
                app.LowerboundSpinner.Enable = true;
                app.UpperboundSpinner.Enable = true;
            else
                % If don't consider misestimation, disable bounds
                app.LowerboundSpinner.Enable = false;
                app.UpperboundSpinner.Enable = false;
            end
        end

        % Button pushed function: ChecknameButton
        function ChecknameButtonPushed(app, event)
            scenarios = dir(fullfile(app.ScenarioPath, '*.txt'));
            name = app.FilenameEditField.Value;
            extension = '.txt';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.FilenameEditField.Value = strcat(name,extension);
            end
            
            names = {scenarios.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.FilenameEditField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.FilenameEditField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: BrowseScenarioPath
        function BrowseScenarioPathButtonPushed(app, event)
            currentPath = app.PathnameEditField.Value;
            app.ScenarioPath = uigetdir(currentPath);
            app.PathnameEditField.Value = app.ScenarioPath;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.EditData;
            oldData = event.PreviousData;
            data = app.UITable.Data;
            if any(newData < 0)
                % Negative value entered, incorrect value
                fig = uifigure;
                uialert(fig,'Must be a positive value','');
                data(indices(1),indices(2)) = {oldData};
            else
                data(indices(1),indices(2)) = {newData};
            end
            app.UITable.Data = data;
        end

        % Button pushed function: AddnewcontrollerButton
        function AddControllerButtonPushed(app, event)
            % [path, controller] = uigetfile("SimuladorT1DM\controllers\");
            % Open window with new controller values
            app.AddnewcontrollerButton.Enable = false;
            app.ControllerOptionsApp = ControllerOptions(app);
            
        end

        % Button pushed function: ImportscenarioButton
        function ImportscenarioButtonPushed(app, event)
            [name, path] = uigetfile("SimuladorT1DM\scenarios\");
            app.ImportedScenario = name;
            app.ScenarioName = name;
            app.ScenarioPath = path;
            filename = fullfile(app.ScenarioPath,app.ScenarioName);
            scenario = load(filename);
            
            % Save values
            app.SelectedPatients = scenario.patients;
            app.ScenarioValues = scenario.controller;
            app.BasalValue = scenario.controller.basal;
            app.CRValue = scenario.controller.CR;
            
            % Show values
            app.basalSpinner.Value = app.BasalValue;
            app.CRSpinner.Value = app.CRValue;
            
            if scenario.CL == true
                app.ClosedLoopCheckBox.Value = true;
                CLCheckBoxValueChanged(app,true);
                app.ControllerCL = scenario.controllerName;
                ReadValuesFromControllerFile(app,app.ControllerCL);
                app.ControllersDropDown.Value = app.ControllerCL;
            end
            
            data = app.UITable.Data;
            data(1,2) = {app.ScenarioValues.initial_BG};
            data(2,2) = {num2str(app.ScenarioValues.grams_cho)};
            data(3,2) = {num2str(app.ScenarioValues.temps_cho)};
            data(4,2) = {app.ScenarioValues.meal_time};
            data(5,2) = {app.ScenarioValues.sim_time};
            app.UITable.Data = data;
            
            if scenario.MealMisestimation == true
                app.MisestimationCheckBox.Value = true;
                app.LowerBound = scenario.LB;
                app.UpperBound = scenario.UB;
                app.LowerboundSpinner.Value = app.LowerBound;
                app.UpperboundSpinner.Value = app.UpperBound;
            end
            
            app.FilenameEditField.Value = app.ScenarioName;
            app.PathnameEditField.Value = app.ScenarioPath;
            
        end

        % Button pushed function: ResultsSaveName
        function ResultsCheckNameButtonPushed(app, event)
            results = dir(fullfile(app.ResultsPath, '*.txt'));
            name = app.ResultsNameField.Value;
            extension = '.txt';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.ResultsNameField.Value = strcat(name,extension);
            end
            
            names = {results.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.ResultsNameField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.ResultsNameField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: BrowsepathButton
        function BrowsepathButtonPushed(app, event)
            currentPath = app.ResultsPathField.Value;
            app.ResultsPath = uigetdir(currentPath);
            app.ResultsPathField.Value = app.ResultsPath;
        end

        % Value changed function: CRSpinner
        function CRSpinnerValueChanged(app, event)
            value = app.CRSpinner.Value;
            app.CRValue = value;
        end

        % Value changed function: basalSpinner
        function basalSpinnerValueChanged(app, event)
            value = app.basalSpinner.Value;
            app.BasalValue = value;
        end

        % Value changed function: ControllersDropDown
        function ControllersDropDownValueChanged(app, event)
            controller = app.ControllersDropDown.Value;
            app.ControllerCL = controller;
            
            % Read values from controller file
            ReadValuesFromControllerFile(app,controller);
            
            % Open new window with read values
            controllerName = strcat(controller,' values');
            fig = uifigure('Name', controllerName);
            fig.Position(3:4) = [240 400];
            v_names = ["k0", "k1", "k2", "Target", "Gamma", "Kp", "Ti", "Td", "SG", "Ip_1", "Ip_2", "In_1", "ID_1", "IDn"]';
            variables = struct2cell(app.CLVariables);
            table_names = table(v_names,variables,'VariableNames',{'Name','Value'});
            uitable(fig,'Data', table_names, 'Position',[20 20 200 343]);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Value changed function: TimeblocksCheckBox
        function TimeblocksCheckBoxValueChanged(app, event)
            enable = app.TimeblocksCheckBox.Enable;
            value = app.TimeblocksCheckBox.Value;
            time = app.TimePanel.Children;
            if value == true
                if enable == true
                    set(time,'Enable',true);
                else
                    set(time,'Enable',false);
                end
            else
                set(time,'Enable',false);
            end
        end

        % Value changed function: ShowCVGACheckBox
        function ShowCVGACheckBoxValueChanged(app, event)
            value = app.ShowCVGACheckBox.Value;
            cgm = app.CGMPanel.Children;
            if value == true
                set(cgm,'Enable',true);
                app.TimeblocksCheckBox.Enable = true;
                if app.TimeblocksCheckBox.Value == true
                    TimeblocksCheckBoxValueChanged(app,true);
                end
                app.ShowpopulationCGMCheckBox.Enable = true;
                app.ShowindividualpatientCGMCheckBox.Enable = true;
                app.ShowpopulationpumpCheckBox.Enable = true;
                app.ShowindividualpatientpumpCheckBox.Enable = true;
            else
                set(cgm,'Enable',false);
                app.TimeblocksCheckBox.Enable = false;
                TimeblocksCheckBoxValueChanged(app,false);
                app.ShowpopulationCGMCheckBox.Enable = false;
                app.ShowindividualpatientCGMCheckBox.Enable = false;
                app.ShowpopulationpumpCheckBox.Enable = false;
                app.ShowindividualpatientpumpCheckBox.Enable = false;
            end
        end

        % Value changed function: LowerboundSpinner
        function LowerboundSpinnerValueChanged(app, event)
            app.LowerBound = app.LowerboundSpinner.Value;
        end

        % Value changed function: UpperboundSpinner
        function UpperboundSpinnerValueChanged(app, event)
            app.UpperBound = app.UpperboundSpinner.Value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Comprovar dades
            
            % Get data from app or imported data
            if app.ImportscenariofomfileCheckBox.Value == true
                controller = app.ScenarioValues;
            else
                controller.initial_BG = str2num(cell2mat(app.UITable.Data(1,2))); %#ok<ST2NM> 
                controller.grams_cho = str2num(cell2mat(app.UITable.Data(2,2))); %#ok<ST2NM> 
                controller.temps_cho = str2num(cell2mat(app.UITable.Data(3,2))); %#ok<ST2NM> 
                controller.meal_time = str2num(cell2mat(app.UITable.Data(4,2))); %#ok<ST2NM> 
                controller.sim_time = str2num(cell2mat(app.UITable.Data(5,2))); %#ok<ST2NM> 
                controller.basal = app.BasalValue;
                controller.CR = app.CRValue;
            end
            
            % Check if we want to save the scenario
            if app.SavescenarioCheckBox.Value == true
                % Create mat file
                filename = fullfile(app.ScenarioPath,app.ScenarioName);
                m = matfile(filename,'Writable',true);
                
                % Save scenario values
                m.patients = app.SelectedPatients;
                m.controller = controller;
                if app.MisestimationCheckBox.Value == true
                    m.MealMisestimation = true;
                    m.LB = app.LowerBound;
                    m.UB = app.UpperBound;
                else
                    m.MealMisestimation = false;
                end
                if app.ClosedLoopCheckBox.Value == true
                    m.CL = true;
                    m.controllerName = app.ControllerCL;
                else
                    m.CL = false;
                end
            end

            if app.ClosedLoopCheckBox.Value == false
                controller.tipus = 'OL';
            else
                % controller.CLparams = app.CLVariables;
                controller.tipus = 'PID';
                controller.k0 = app.CLVariables.k0;
                controller.k1 = app.CLVariables.k1;
                controller.k2 = app.CLVariables.k2;
                controller.target = app.CLVariables.target;
                controller.gamma = app.CLVariables.gamma;
                controller.Kp = app.CLVariables.Kp;
                controller.Ti = app.CLVariables.Ti;
                controller.Td = app.CLVariables.Td;
                controller.SG = app.CLVariables.SG;
                controller.Ip_1 = app.CLVariables.Ip_1;
                controller.Ip_2 = app.CLVariables.Ip_2;
                controller.In_1 = app.CLVariables.In_1;
                controller.ID_1 = app.CLVariables.ID_1;
                controller.IDn = app.CLVariables.IDn;
            end
            
            patients_lenght = size(app.SelectedPatients);
            %patients = zeros(patients_lenght);
            for i=1:patients_lenght(2)
                patient_file = strcat(app.SelectedPatients{i}, '.csv');
                controller.pacient = csvread(strcat('SimuladorT1DM\patients\',patient_file));
                %patients(i) = controller.pacient;
                mainCL(controller);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%% SHOW RESULTS %%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %if app.ShowCVGACheckBox.Value == true
            %    if app.ShowpopulationCGMCheckBox.Value == true
            %        ShowCGMMetrics(app,'population',patients);
            %    end
            %    if app.ShowindividualpatientCGMCheckBox.Value == true
            %        ShowCGMMetrics(app,'individual',patients);
            %    end
            %    if app.ShowpopulationpumpCheckBox.Value == true
            %        ShowPumpMetrics(app,'population',patients);
            %    end
            %    if app.ShowindividualpatientpumpCheckBox.Value == true
            %        ShowPumpMetrics(app,'individual',patients);
            %    end
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.ClosedLoopCheckBox = uicheckbox(app.SimulationTab);
            app.ClosedLoopCheckBox.ValueChangedFcn = createCallbackFcn(app, @CLCheckBoxValueChanged, true);
            app.ClosedLoopCheckBox.Text = 'Closed Loop?';
            app.InsulinunitsDropDownLabel = uilabel(app.SimulationTab);
            app.InsulinunitsDropDownLabel.Text = 'Insulin units';
            app.InsulinunitsDropDown = uidropdown(app.SimulationTab);
            app.InsulinunitsDropDown.Items = {'U/h', 'mmol/kg/min'};
            app.InsulinunitsDropDown.Value = 'U/h';
            app.GlycemiaunitsDropDownLabel = uilabel(app.SimulationTab);
            app.GlycemiaunitsDropDownLabel.Text = 'Glycemia units';
            app.GlycemiaunitsDropDown = uidropdown(app.SimulationTab);
            app.GlycemiaunitsDropDown.Items = {'mg/dl', 'mmol/L'};
            app.GlycemiaunitsDropDown.Value = 'mg/dl';
            app.CLPanel = uipanel(app.SimulationTab);
            app.AddnewcontrollerButton = uibutton(app.CLPanel, 'push');
            app.AddnewcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @AddControllerButtonPushed, true);
            app.AddnewcontrollerButton.Text = 'Add new controller';
            app.ControllersDropDownLabel = uilabel(app.CLPanel);
            app.ControllersDropDownLabel.Text = 'Controllers';
            app.ControllersDropDown = uidropdown(app.CLPanel);
            app.ControllersDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllersDropDownValueChanged, true);
            app.OLPanel = uipanel(app.SimulationTab);
            app.basalSpinnerLabel = uilabel(app.OLPanel);
            app.basalSpinnerLabel.Text = 'basal';
            app.basalSpinner = uispinner(app.OLPanel);
            app.basalSpinner.Limits = [0 Inf];
            app.basalSpinner.ValueChangedFcn = createCallbackFcn(app, @basalSpinnerValueChanged, true);
            app.CRSpinnerLabel = uilabel(app.OLPanel);
            app.CRSpinnerLabel.Text = 'CR';
            app.CRSpinner = uispinner(app.OLPanel);
            app.CRSpinner.Limits = [0 Inf];
            app.CRSpinner.ValueChangedFcn = createCallbackFcn(app, @CRSpinnerValueChanged, true);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ImportscenarioButton = uibutton(app.ScenarioTab, 'push');
            app.ImportscenarioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportscenarioButtonPushed, true);
            app.ImportscenarioButton.Text = 'Import scenario';
            app.PatientsPanel = uipanel(app.ScenarioTab);
            app.AvailablepatientsListBoxLabel = uilabel(app.PatientsPanel);
            app.AvailablepatientsListBoxLabel.Text = 'Available patients';
            app.AvailablePatientsListBox = uilistbox(app.PatientsPanel);
            app.AvailablePatientsListBox.Items = {'Item 1', 'Item 5', 'Item 6', 'Item 7', 'Item 2', 'Item 3', 'Item 4'};
            app.AvailablePatientsListBox.Multiselect = 'on';
            app.AvailablePatientsListBox.Value = {'Item 5'};
            app.AddpatientstosimulationButton = uibutton(app.PatientsPanel, 'push');
            app.AddpatientstosimulationButton.ButtonPushedFcn = createCallbackFcn(app, @AddPatientsButtonPushed, true);
            app.AddpatientstosimulationButton.Text = 'Add patients to simulation';
            app.ViewselectedpatientsButton = uibutton(app.PatientsPanel, 'push');
            app.ViewselectedpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSelectedPatientsButtonPushed, true);
            app.ViewselectedpatientsButton.Text = 'View selected patients';
            app.MealsPanel = uipanel(app.ScenarioTab);
            app.MisestimationCheckBox = uicheckbox(app.MealsPanel);
            app.MisestimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MealMisestimationCheckBoxValueChanged, true);
            app.MisestimationCheckBox.Text = 'Consider meal misestimation? [%]';
            app.LowerboundSpinnerLabel = uilabel(app.MealsPanel);
            app.LowerboundSpinnerLabel.Text = 'Lower bound';
            app.LowerboundSpinner = uispinner(app.MealsPanel);
            app.LowerboundSpinner.Limits = [0 Inf];
            app.LowerboundSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerboundSpinnerValueChanged, true);
            app.UpperboundSpinnerLabel = uilabel(app.MealsPanel);
            app.UpperboundSpinnerLabel.Text = 'Upper bound';
            app.UpperboundSpinner = uispinner(app.MealsPanel);
            app.UpperboundSpinner.Limits = [0 Inf];
            app.UpperboundSpinner.ValueChangedFcn = createCallbackFcn(app, @UpperboundSpinnerValueChanged, true);
            app.UITable = uitable(app.MealsPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ScenarioSavingPanel = uipanel(app.ScenarioTab);
            app.ModifyScenarioSavingCheckBox = uicheckbox(app.ScenarioSavingPanel);
            app.ModifyScenarioSavingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifyScenarioSavingCheckBoxValueChanged, true);
            app.ModifyScenarioSavingCheckBox.Text = 'Modify the scenario saving configuration?';
            app.SavescenarioCheckBox = uicheckbox(app.ScenarioSavingPanel);
            app.SavescenarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveScenarioCheckBoxValueChanged, true);
            app.SavescenarioCheckBox.Text = 'Save scenario?';
            app.ModifySavingPanel = uipanel(app.ScenarioSavingPanel);
            app.PathnameEditField = uieditfield(app.ModifySavingPanel, 'text');
            app.PathnameEditField.Editable = 'off';
            app.ChecknameButton = uibutton(app.ModifySavingPanel, 'push');
            app.ChecknameButton.ButtonPushedFcn = createCallbackFcn(app, @ChecknameButtonPushed, true);
            app.ChecknameButton.Text = 'Check name';
            app.BrowseScenarioPath = uibutton(app.ModifySavingPanel, 'push');
            app.BrowseScenarioPath.ButtonPushedFcn = createCallbackFcn(app, @BrowseScenarioPathButtonPushed, true);
            app.BrowseScenarioPath.Text = 'Browse path';
            app.FilenameEditFieldLabel = uilabel(app.ModifySavingPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ModifySavingPanel, 'text');
            app.ChangePathLabel = uilabel(app.ModifySavingPanel);
            app.ChangePathLabel.Text = 'Change Path';
            app.ImportscenariofomfileCheckBox = uicheckbox(app.ScenarioTab);
            app.ImportscenariofomfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImportScenarioCheckBoxValueChanged, true);
            app.ImportscenariofomfileCheckBox.Text = 'Import scenario fom file?';
            app.ResultsfileTab = uitab(app.TabGroup);
            app.ResultsfileTab.Title = 'Results file';
            app.ResultsFilePanel = uipanel(app.ResultsfileTab);
            app.ResultsNameField = uieditfield(app.ResultsFilePanel, 'text');
            app.ResultsPathField = uieditfield(app.ResultsFilePanel, 'text');
            app.ResultsPathField.Editable = 'off';
            app.BrowsepathButton = uibutton(app.ResultsFilePanel, 'push');
            app.BrowsepathButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsepathButtonPushed, true);
            app.BrowsepathButton.Text = 'Browse path';
            app.ResultsSaveName = uibutton(app.ResultsFilePanel, 'push');
            app.ResultsSaveName.ButtonPushedFcn = createCallbackFcn(app, @ResultsCheckNameButtonPushed, true);
            app.ResultsSaveName.Text = 'Check name';
            app.PathLabel = uilabel(app.ResultsFilePanel);
            app.PathLabel.Text = 'Path';
            app.NameLabel = uilabel(app.ResultsFilePanel);
            app.NameLabel.Text = 'Name';
            app.OutputPanel = uipanel(app.ResultsfileTab);
            app.OutputPanel.Title = 'Output';
            app.ShowCVGACheckBox = uicheckbox(app.OutputPanel);
            app.ShowCVGACheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCVGACheckBoxValueChanged, true);
            app.ShowCVGACheckBox.Text = 'Show CVGA?';
            app.ShowpopulationCGMCheckBox = uicheckbox(app.OutputPanel);
            app.ShowpopulationCGMCheckBox.Text = 'Show population CGM?';
            app.ShowindividualpatientCGMCheckBox = uicheckbox(app.OutputPanel);
            app.ShowindividualpatientCGMCheckBox.Text = 'Show individual patient CGM?';
            app.ShowpopulationpumpCheckBox = uicheckbox(app.OutputPanel);
            app.ShowpopulationpumpCheckBox.Text = 'Show population pump?';
            app.ShowindividualpatientpumpCheckBox = uicheckbox(app.OutputPanel);
            app.ShowindividualpatientpumpCheckBox.Text = 'Show individual patient pump?';
            app.StandarizedCGMmetricsPanel = uipanel(app.OutputPanel);
            app.StandarizedCGMmetricsPanel.Title = 'Standarized CGM metrics';
            app.TimeblocksCheckBox = uicheckbox(app.StandarizedCGMmetricsPanel);
            app.TimeblocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeblocksCheckBoxValueChanged, true);
            app.TimeblocksCheckBox.Text = 'Time blocks?';
            app.TimePanel = uipanel(app.StandarizedCGMmetricsPanel);
            app.hblocksCheckBox = uicheckbox(app.TimePanel);
            app.hblocksCheckBox.Text = '24-h blocks';
            app.DayblocksCheckBox = uicheckbox(app.TimePanel);
            app.DayblocksCheckBox.Text = 'Day blocks';
            app.NightblocksCheckBox = uicheckbox(app.TimePanel);
            app.NightblocksCheckBox.Text = 'Night blocks';
            app.CGMPanel = uipanel(app.StandarizedCGMmetricsPanel);
            app.MeanglucoseCheckBox = uicheckbox(app.CGMPanel);
            app.MeanglucoseCheckBox.Text = 'Mean glucose';
            app.GlycemicvariabilityCheckBox = uicheckbox(app.CGMPanel);
            app.GlycemicvariabilityCheckBox.Text = 'Glycemic variability';
            app.Time250mgdLCheckBox = uicheckbox(app.CGMPanel);
            app.Time250mgdLCheckBox.Text = 'Time > 250 mg/dL';
            app.Time70180mgdLCheckBox = uicheckbox(app.CGMPanel);
            app.Time70180mgdLCheckBox.Text = 'Time 70-180 mg/dL';
            app.Time70mgdLCheckBox = uicheckbox(app.CGMPanel);
            app.Time70mgdLCheckBox.Text = 'Time < 70 mg/dL';
            app.Time54mgdLCheckBox = uicheckbox(app.CGMPanel);
            app.Time54mgdLCheckBox.Text = 'Time < 54 mg/dL';
            app.LBGIriskindexCheckBox = uicheckbox(app.CGMPanel);
            app.LBGIriskindexCheckBox.Text = 'LBGI risk index';
            app.HBGIriskindexCheckBox = uicheckbox(app.CGMPanel);
            app.HBGIriskindexCheckBox.Text = 'HBGI risk index';
            app.AreaunderthecurveCheckBox = uicheckbox(app.CGMPanel);
            app.AreaunderthecurveCheckBox.Text = 'Area under the curve';
            app.Time180mgdLCheckBox = uicheckbox(app.CGMPanel);
            app.Time180mgdLCheckBox.Text = 'Time > 180 mg/dL';
            app.ResultsFileCheckBox = uicheckbox(app.ResultsfileTab);
            app.ResultsFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultsFileCheckBoxValueChanged, true);
            app.ResultsFileCheckBox.Text = 'Change the results file configuration?';
            app.SaveresultsCheckBox = uicheckbox(app.ResultsfileTab);
            app.SaveresultsCheckBox.Text = 'Save results?';
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.CGMsensorCheckBox = uicheckbox(app.HardwareTab);
            app.CGMsensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CGMsensorCheckBoxValueChanged, true);
            app.CGMsensorCheckBox.Text = 'Select CGM sensor?';
            app.CGMdrop = uidropdown(app.HardwareTab);
            app.CSIICheckBox = uicheckbox(app.HardwareTab);
            app.CSIICheckBox.ValueChangedFcn = createCallbackFcn(app, @CSIICheckBoxValueChanged, true);
            app.CSIICheckBox.Text = 'Select CSII?';
            app.CSIIdrop = uidropdown(app.HardwareTab);
            app.ConfigurationLabel = uilabel(app.UIFigure);
            app.ConfigurationLabel.Text = 'Configuration';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimuladorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end